classdef arduinoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        STOPButton                   matlab.ui.control.Button
        AARCOASISLabel               matlab.ui.control.Label
        DirectionKnobLabel           matlab.ui.control.Label
        DirectionKnob                matlab.ui.control.DiscreteKnob
        SpeedSliderLabel             matlab.ui.control.Label
        SpeedSlider                  matlab.ui.control.Slider
        CurrentModeButtonGroup       matlab.ui.container.ButtonGroup
        AutomaticButton              matlab.ui.control.RadioButton
        ManualButton                 matlab.ui.control.RadioButton
        ManualCommandsLabel          matlab.ui.control.Label
        PositionKnobLabel            matlab.ui.control.Label
        PositionKnob                 matlab.ui.control.DiscreteKnob
        WOBNEditFieldLabel           matlab.ui.control.Label
        WOBNEditField                matlab.ui.control.NumericEditField
        LimitSwitchReachedLampLabel  matlab.ui.control.Label
        LimitSwitchReachedLamp       matlab.ui.control.Lamp
        RPMSliderLabel               matlab.ui.control.Label
        RPMSlider                    matlab.ui.control.Slider
    end

    
    properties (Access = public)
        App_Closed
        ROP_Speed_Cmd
        ROP_Direction_Cmd
        Drilling_Mode
        Mirage_Position_Cmd
        RPMSpeed_Cmd
    end
    
    methods (Access = private)
        
        function reset(app)
            app.SpeedSlider.Value = 0;
            app.ROP_Speed_Cmd = 0; % move speed multiplier

            app.DirectionKnob.Value = "Stop"; % stop mode
            app.ROP_Direction_Cmd = 0;

            app.PositionKnob.Value = "0";
            app.Mirage_Position_Cmd = 0;

            app.ManualButton.Value = 1;
            app.Drilling_Mode = 1; % manual mode
            
            app.RPMSlider.Value = 0;
            app.RPMSpeed_Cmd = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %na
            app.App_Closed = 0;
            
            app.SpeedSlider.Value = 0;
            app.ROP_Speed_Cmd = 0; % move speed multiplier

            app.DirectionKnob.Value = "Stop"; % stop mode
            app.ROP_Direction_Cmd = 0;

            app.PositionKnob.Value = "0";
            app.Mirage_Position_Cmd = 0;

            app.ManualButton.Value = 1;
            app.Drilling_Mode = 1; % manual mode
            
            app.RPMSlider.Value = 0;
            app.RPMSpeed_Cmd = 0;
        end

        % Value changed function: DirectionKnob
        function DirectionKnobValueChanged(app, event)
            value = app.DirectionKnob.Value; 
            if (value == "Up")
                app.ROP_Direction_Cmd = -1;
            end
            if (value == "Stop")
                app.ROP_Direction_Cmd = 0;
            end
            if (value == "Down")
                app.ROP_Direction_Cmd = 1;
            end
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = app.SpeedSlider.Value;
            app.ROP_Speed_Cmd = value;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
                reset(app);
        end

        % Selection changed function: CurrentModeButtonGroup
        function CurrentModeButtonGroupSelectionChanged(app, event)
            if (app.AutomaticButton.Value) 
                app.Drilling_Mode = 0; %automatic mode
            elseif (app.ManualButton.Value)
                app.Drilling_Mode = 1; %manual mode
            end
        end

        % Value changing function: SpeedSlider
        function SpeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ROP_Speed_Cmd = changingValue;
        end

        % Value changed function: PositionKnob
        function PositionKnobValueChanged(app, event)
            value = app.PositionKnob.Value;
            app.Mirage_Position_Cmd = value; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            reset(app); 
            delete(app); 
        end

        % Value changed function: RPMSlider
        function RPMSliderValueChanged(app, event)
            value = app.RPMSlider.Value;
            app.RPMSpeed_Cmd =value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.AARCOASISLabel = uilabel(app.UIFigure);
            app.AARCOASISLabel.Text = 'AARC - OASIS';
            app.DirectionKnobLabel = uilabel(app.UIFigure);
            app.DirectionKnobLabel.Text = 'Direction Knob';
            app.DirectionKnob = uiknob(app.UIFigure, 'discrete');
            app.DirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.DirectionKnob.ValueChangedFcn = createCallbackFcn(app, @DirectionKnobValueChanged, true);
            app.DirectionKnob.Value = 'Stop';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed Slider';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [0 1000];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedSliderValueChanging, true);
            app.CurrentModeButtonGroup = uibuttongroup(app.UIFigure);
            app.CurrentModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CurrentModeButtonGroupSelectionChanged, true);
            app.CurrentModeButtonGroup.Title = 'Current Mode';
            app.AutomaticButton = uiradiobutton(app.CurrentModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.ManualButton = uiradiobutton(app.CurrentModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.ManualCommandsLabel = uilabel(app.UIFigure);
            app.ManualCommandsLabel.Text = 'Manual Commands';
            app.PositionKnobLabel = uilabel(app.UIFigure);
            app.PositionKnobLabel.Text = 'Position Knob';
            app.PositionKnob = uiknob(app.UIFigure, 'discrete');
            app.PositionKnob.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.PositionKnob.ValueChangedFcn = createCallbackFcn(app, @PositionKnobValueChanged, true);
            app.PositionKnob.Value = '0';
            app.WOBNEditFieldLabel = uilabel(app.UIFigure);
            app.WOBNEditFieldLabel.Text = 'WOB (N)';
            app.WOBNEditField = uieditfield(app.UIFigure, 'numeric');
            app.LimitSwitchReachedLampLabel = uilabel(app.UIFigure);
            app.LimitSwitchReachedLampLabel.Text = 'Limit Switch Reached';
            app.LimitSwitchReachedLamp = uilamp(app.UIFigure);
            app.RPMSliderLabel = uilabel(app.UIFigure);
            app.RPMSliderLabel.Text = 'RPM Slider';
            app.RPMSlider = uislider(app.UIFigure);
            app.RPMSlider.Limits = [0 255];
            app.RPMSlider.ValueChangedFcn = createCallbackFcn(app, @RPMSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end